classdef Run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EulerPoincareOnlyButton         matlab.ui.control.Button
        NMatrixCoupledButton            matlab.ui.control.Button
        CMatrixCoupledButton            matlab.ui.control.Button
        MassMatrixCoupledButton         matlab.ui.control.Button
        CMatrixNoCouplingButton         matlab.ui.control.Button
        MassMatrixNoCouplingButton      matlab.ui.control.Button
        CMatrixInternalDynamicsOnlyButton  matlab.ui.control.Button
        MasMatrixInternalDynamicsOnlyButton  matlab.ui.control.Button
        ValidationBulkRunLabel          matlab.ui.control.Label
        CalculateInertialParametrsatthisConfigurationButton  matlab.ui.control.Button
        InitiateRobotButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ErrorsatthisConfigurationLabel  matlab.ui.control.Label
        ValidateModelagainstSimScapeModelLabel  matlab.ui.control.Label
        MonthlyPaymentEditField         matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        BaseVelocitiesEditField         matlab.ui.control.NumericEditField
        BaseVelocitiesEditFieldLabel    matlab.ui.control.Label
        JointVelocitiesEditField        matlab.ui.control.NumericEditField
        JointVelocitiesEditFieldLabel   matlab.ui.control.Label
        JointPositionsEditField         matlab.ui.control.NumericEditField
        JointPositionsEditFieldLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes_3       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_2       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {699, 699};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {440, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.JointPositionsEditField.Value;
            rate = app.JointVelocitiesEditField.Value/12/100;
            nper = 12*app.BaseVelocitiesEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]); 
            ylim(app.PrincipalInterestUIAxes,'auto');
            
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JointPositionsEditFieldLabel = uilabel(app.LeftPanel);
            app.JointPositionsEditFieldLabel.Text = 'Joint Positions';
            app.JointPositionsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.JointPositionsEditField.Limits = [0 10000000];
            app.JointPositionsEditField.ValueDisplayFormat = '%8.f';
            app.JointPositionsEditField.Value = 300000;
            app.JointVelocitiesEditFieldLabel = uilabel(app.LeftPanel);
            app.JointVelocitiesEditFieldLabel.Text = 'Joint Velocities';
            app.JointVelocitiesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.JointVelocitiesEditField.Limits = [0.001 100];
            app.JointVelocitiesEditField.Value = 4;
            app.BaseVelocitiesEditFieldLabel = uilabel(app.LeftPanel);
            app.BaseVelocitiesEditFieldLabel.Text = 'Base Velocities';
            app.BaseVelocitiesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BaseVelocitiesEditField.Limits = [10 40];
            app.BaseVelocitiesEditField.ValueDisplayFormat = '%.0f';
            app.BaseVelocitiesEditField.Value = 30;
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.MonthlyPaymentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField.Editable = 'off';
            app.ValidateModelagainstSimScapeModelLabel = uilabel(app.LeftPanel);
            app.ValidateModelagainstSimScapeModelLabel.Text = {'Validate Model against '; 'SimScape Model'};
            app.ErrorsatthisConfigurationLabel = uilabel(app.LeftPanel);
            app.ErrorsatthisConfigurationLabel.Text = 'Errors at this Configuration';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.InitiateRobotButton = uibutton(app.LeftPanel, 'push');
            app.InitiateRobotButton.Text = 'Initiate Robot';
            app.CalculateInertialParametrsatthisConfigurationButton = uibutton(app.LeftPanel, 'push');
            app.CalculateInertialParametrsatthisConfigurationButton.Text = {'Calculate Inertial Parametrs'; 'at this Configuration'};
            app.ValidationBulkRunLabel = uilabel(app.LeftPanel);
            app.ValidationBulkRunLabel.Text = 'Validation Bulk Run';
            app.MasMatrixInternalDynamicsOnlyButton = uibutton(app.LeftPanel, 'push');
            app.MasMatrixInternalDynamicsOnlyButton.Text = 'Mas Matrix, Internal Dynamics Only';
            app.CMatrixInternalDynamicsOnlyButton = uibutton(app.LeftPanel, 'push');
            app.CMatrixInternalDynamicsOnlyButton.Text = 'C Matrix, Internal Dynamics Only';
            app.MassMatrixNoCouplingButton = uibutton(app.LeftPanel, 'push');
            app.MassMatrixNoCouplingButton.Text = 'Mass Matrix,No Coupling';
            app.CMatrixNoCouplingButton = uibutton(app.LeftPanel, 'push');
            app.CMatrixNoCouplingButton.Text = 'C Matrix,No Coupling';
            app.MassMatrixCoupledButton = uibutton(app.LeftPanel, 'push');
            app.MassMatrixCoupledButton.Text = 'Mass Matrix,Coupled';
            app.CMatrixCoupledButton = uibutton(app.LeftPanel, 'push');
            app.CMatrixCoupledButton.Text = 'C Matrix,Coupled';
            app.NMatrixCoupledButton = uibutton(app.LeftPanel, 'push');
            app.NMatrixCoupledButton.Text = 'N Matrix,Coupled';
            app.EulerPoincareOnlyButton = uibutton(app.LeftPanel, 'push');
            app.EulerPoincareOnlyButton.Text = 'Euler-Poincare Only';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Acceleration Error')
            xlabel(app.PrincipalInterestUIAxes, 'Time (s)')
            ylabel(app.PrincipalInterestUIAxes, 'Rad')
            app.PrincipalInterestUIAxes_2 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_2, 'Velocity Error')
            xlabel(app.PrincipalInterestUIAxes_2, 'Time (s)')
            ylabel(app.PrincipalInterestUIAxes_2, 'Rad')
            app.PrincipalInterestUIAxes_3 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_3, 'Position Error')
            xlabel(app.PrincipalInterestUIAxes_3, 'Time (s)')
            ylabel(app.PrincipalInterestUIAxes_3, 'Rad')
            app.ContextMenu = uicontextmenu(app.MortgageCalculatorUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end